classdef hAppythelium < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UndoButton         matlab.ui.control.Button
        DeleteAreaButton   matlab.ui.control.Button
        SaveCheckBox       matlab.ui.control.CheckBox
        StatusLampLabel    matlab.ui.control.Label
        StatusLamp         matlab.ui.control.Lamp
        numCell            matlab.ui.control.Label
        table_props        matlab.ui.control.Table
        txtPathLabel       matlab.ui.control.Label
        ImageListBox       matlab.ui.control.ListBox
        SaveButton         matlab.ui.control.Button
        ImageListBoxLabel  matlab.ui.control.Label
        ChangeButton       matlab.ui.control.Button
        ax_raw             matlab.ui.control.UIAxes
        ax_proc            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %
        % img_path = '/Users/tardis/Documents/GitHub/Retina/rpe_examples/controlli'; % Description
        img_path = "";
        saveFile = "";
        img_list = []; % Description
        img_sel = 1;
        extension='.tif'
        indices = -1;
        ifsave
    end
    
    methods (Access = private)
        
        function image_fun(app)

            app.StatusLamp.Color = 'r';
            if exist(strcat(app.img_path, filesep,app.img_sel,app.extension))==2
                app.StatusLamp.Color = 'r';
                %[raw, lbl, props] = rpe_preproc(strcat(app.img_path, filesep,app.img_sel));
                img = app.saveFile.(app.img_sel);
                app.SaveCheckBox.Value = img.ifsave;
                sana = imagesc(app.ax_raw, img.raw);
                title(app.ax_raw,'')
                xlabel(app.ax_raw,'')
                ylabel(app.ax_raw,'')
                app.ax_raw.XAxis.TickLabels = {};
                app.ax_raw.YAxis.TickLabels = {};
                app.ax_raw.XLim = [0 sana.XData(2)];
                app.ax_raw.YLim = [0 sana.YData(2)];
    
                proc = imagesc(app.ax_proc, img.lbl);
                app.ax_proc.XAxis.TickLabels = {};
                app.ax_proc.YAxis.TickLabels = {};
                app.ax_proc.XLim = [0 proc.XData(2)];
                app.ax_proc.YLim = [0 proc.YData(2)];
                hold(app.ax_proc,'on')
                plot(app.ax_proc,img.props.x,img.props.y,'s','MarkerFaceColor','r','MarkerEdgeColor',[0,0,0])
                if app.indices>-1
                   plot(app.ax_proc,img.props.x(app.indices),img.props.y(app.indices),'o','MarkerFaceColor','k','MarkerEdgeColor',[1,1,1],'MarkerSize',9)
                end
                title(app.ax_proc,'')
                xlabel(app.ax_proc,'')
                ylabel(app.ax_proc,'')
                hold(app.ax_proc,'off')
    
                linkaxes([app.ax_raw, app.ax_proc],'xy')
                colormap(app.ax_proc,'colorcube')
                
                app.SaveCheckBox.Value = img.ifsave;

                app.table_props.ColumnName = img.props.Properties.VariableNames;
                app.table_props.Data = img.props;
                app.numCell.Text = strcat('N cells:', string( height(img.props)) );
            end
            app.StatusLamp.Color = 'g';
        end
        
        function app = initPath(app)
            app.StatusLamp.Color = 'r';
            drawnow;
            app.saveFile = matfile(strcat(app.img_path,filesep,'dati.mat'),"Writable",true);
            if length(fields(app.saveFile))>2
               app.txtPathLabel.BackgroundColor = [0.5,1,0.5];
               app.txtPathLabel.Text = 'Config file found for this folder!'; 
               drawnow;
            end
            
            app.img_list = dir(fullfile(app.img_path, strcat('*',app.extension)));
            app.img_list = {app.img_list.name} ;
            for i = 1:length(app.img_list)
                tmp = split(app.img_list{i},'.');
                app.img_list{i} = tmp{1};
            end
            app.ImageListBox.Items = app.img_list;
            app.txtPathLabel.BackgroundColor = [0.8,0.8,0.8];
            app.txtPathLabel.Text = app.img_path;
            drawnow;
            app.ImageListBox.Value = app.img_list{1};
            app.img_sel = app.img_list{1};
            for i = 1:length(app.img_list)
                if ~misField(app.saveFile,app.img_list{i})
                    app.txtPathLabel.BackgroundColor = 	[0.5 1 0.5];
                    app.txtPathLabel.Text = strcat('PLEASE WAIT!! Loading images: ', app.img_list{i});
                    drawnow;

                    [raw, lbl, props] = rpe_preproc(strcat(app.img_path, filesep,app.img_list{i},app.extension));
                    app.saveFile.(app.img_list{i})=struct('raw',raw,'lbl',lbl,'props',props,'ifsave',true);
                    
                    app.txtPathLabel.BackgroundColor = 	[0.8 0.8 0.8];
                    app.txtPathLabel.Text = app.img_path;
                end
                
            end
            app.img_sel = app.img_list{1};
            app.StatusLamp.Color = 'g';
        end
        
        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax);
            x = roi.Position(1);
            y = roi.Position(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %if isempty(app.img_path)
            disp(app.txtPathLabel.BackgroundColor)
            app.img_path = uigetdir(app.img_path);
            if ~app.img_path
                closereq;
            else
                app = initPath(app);
                image_fun(app);
                
            end
            
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)
            app.img_sel = app.ImageListBox.Value;
            image_fun(app);
            %disp(app.img_sel)
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            pre = app.img_path;
            app.img_path = uigetdir(app.img_path);
            if ~app.img_path
                app.img_path = pre;
            else
                app = initPath(app);
                image_fun(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.txtPathLabel.BackgroundColor = [0.7,1,0.5];
           app.txtPathLabel.Text = 'SAVING...'; 
           drawnow;
            [filepath,name,ext] = fileparts(app.img_path);
            filename = strcat(datestr(datetime,'yyyymmdd_HHMM'),'-',name,'-hAppy.xlsx');
            [file,path] = uiputfile(filename);
            filename = strcat(path,file);
            summary = table();

            for i = 1:length(app.img_list)
                tmp = app.saveFile.(app.img_list{i});
                if ~tmp.ifsave
                    app.txtPathLabel.BackgroundColor = [0.5,1,0.7];
                    app.txtPathLabel.Text = strcat('Skipping:',app.img_list{i},'...');
                    drawnow;
                    continue
                else
                    app.txtPathLabel.BackgroundColor = [0.7,1,0.5];
                    app.txtPathLabel.Text = strcat('Saving: ',app.img_list{i},'...');
                    drawnow;
                    N_cells = height(tmp.props);
                    Area = mean(tmp.props.Area);
                    Perimeter = mean(tmp.props.Perimeter);
                    Eccentricity = mean(tmp.props.Eccentricity);
                    Orientation = mean(tmp.props.Orientation);
                    Circularity = mean(tmp.props.Circularity);
                    tbl = table(N_cells,Area,Perimeter,Eccentricity,Orientation,Circularity);
                    summary = cat(1,summary,tbl);
                    sheetname=app.img_list{i};
                    len = length(sheetname);
                    %sheetname = strsplit(sheetname,'_');
                    %strjoin(sheetname,'/')
                    if len>29
                       %sheetname =erase(app.img_list{i},'_');
                       sheetname =sheetname((len-20):len);
                    end
                    writetable(tmp.props,filename,'Sheet',sheetname);
                end
            end
             writetable(summary,filename,'Sheet','Summary');

             app.txtPathLabel.BackgroundColor = [0.8,0.8,0.8];
             app.txtPathLabel.Text = app.img_path; 
             drawnow
        end

        % Value changed function: SaveCheckBox
        function SaveCheckBoxValueChanged(app, event)
            value = app.SaveCheckBox.Value;
            temp = app.saveFile.(app.img_sel);
            temp.ifsave = value;
            app.saveFile.(app.img_sel) = temp;
        end

        % Cell selection callback: table_props
        function table_propsCellSelection(app, event)
            indices = event.Indices;
            app.indices=indices(1);
            image_fun(app);
            
        end

        % Button pushed function: DeleteAreaButton
        function DeleteAreaButtonPushed(app, event)
            res = drawrectangle(app.ax_proc);
            img = app.saveFile.(app.img_sel);
            res.Position = [res.Position(1),res.Position(2),res.Position(1)+res.Position(3),res.Position(2)+res.Position(4)];
            in=find(img.props.x>res.Position(1) & img.props.x<res.Position(3) & img.props.y>res.Position(2) & img.props.y<res.Position(4));
            if in
                img.props(in,:)=[];
                app.saveFile.(app.img_sel) = img;
            end
            image_fun(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            [raw, lbl, props] = rpe_preproc(strcat(app.img_path, filesep,app.img_sel,app.extension));
            app.saveFile.(app.img_sel)=struct('raw',raw,'lbl',lbl,'props',props,'ifsave',true);
            image_fun(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_proc = uiaxes(app.UIFigure);
            title(app.ax_proc, 'Title')
            xlabel(app.ax_proc, 'X')
            ylabel(app.ax_proc, 'Y')
            zlabel(app.ax_proc, 'Z')
            app.ax_raw = uiaxes(app.UIFigure);
            title(app.ax_raw, 'Title')
            xlabel(app.ax_raw, 'X')
            ylabel(app.ax_raw, 'Y')
            zlabel(app.ax_raw, 'Z')
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.ImageListBoxLabel = uilabel(app.UIFigure);
            app.ImageListBoxLabel.Text = 'Image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ImageListBox = uilistbox(app.UIFigure);
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.txtPathLabel = uilabel(app.UIFigure);
            app.txtPathLabel.Text = 'txtPath';
            app.table_props = uitable(app.UIFigure);
            app.table_props.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table_props.RowName = {};
            app.table_props.CellSelectionCallback = createCallbackFcn(app, @table_propsCellSelection, true);
            app.numCell = uilabel(app.UIFigure);
            app.numCell.Text = '';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.SaveCheckBox = uicheckbox(app.UIFigure);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBoxValueChanged, true);
            app.SaveCheckBox.Text = 'Save';
            app.DeleteAreaButton = uibutton(app.UIFigure, 'push');
            app.DeleteAreaButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAreaButtonPushed, true);
            app.DeleteAreaButton.Text = 'Delete Area';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hAppythelium

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end